classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Lamp_ARM                  matlab.ui.control.Lamp
        Lamp_power                matlab.ui.control.Lamp
        StatusLampLabel           matlab.ui.control.Label
        Lamp_redInv               matlab.ui.control.Lamp
        Lamp_greenInv             matlab.ui.control.Lamp
        Lamp_blueInv              matlab.ui.control.Lamp
        Lamp_blueCap              matlab.ui.control.Lamp
        Lamp_greenCap             matlab.ui.control.Lamp
        Lamp_redCap               matlab.ui.control.Lamp
        ARMSwitch                 matlab.ui.control.ToggleSwitch
        ARMSwitchLabel            matlab.ui.control.Label
        PowerButton               matlab.ui.control.StateButton
        EmergencyStopButton       matlab.ui.control.StateButton
        BlueCapacityGauge         matlab.ui.control.LinearGauge
        BlueCapacityGaugeLabel    matlab.ui.control.Label
        GreenCapacityGauge        matlab.ui.control.LinearGauge
        GreenCapacityGaugeLabel   matlab.ui.control.Label
        RedCapacityGauge          matlab.ui.control.LinearGauge
        RedCapacityGaugeLabel     matlab.ui.control.Label
        BlueButton                matlab.ui.control.Button
        BlueInventoryGauge        matlab.ui.control.LinearGauge
        BlueInventoryGaugeLabel   matlab.ui.control.Label
        GreenButton               matlab.ui.control.Button
        GreenInventoryGauge       matlab.ui.control.LinearGauge
        GreenInventoryGaugeLabel  matlab.ui.control.Label
        RedInventoryGauge         matlab.ui.control.LinearGauge
        RedInventoryGaugeLabel    matlab.ui.control.Label
        SelectInkCartridgetoChangeLabel  matlab.ui.control.Label
        RedButton                 matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ARMSwitch, PowerButton
        function powerOn(app, event)
            value = app.PowerButton.Value;
            if value == 1
                app.Lamp_power.Color = [0,1,0];
            else
                app.Lamp_power.Color = [0.5,0.5,0.5];
            end
        end

        % Callback function
        function ARM_switch(app, event)
            value = app.ARMSwitch.Value;
            if strcmp(app.ARMSwitch.Value,'On') && app.PowerButton.Value == 1
                app.Lamp_ARM.Color = [0,1,0];
            else
                app.Lamp_ARM.Color = [0.5,0.5,0.5];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RedButton = uibutton(app.UIFigure, 'push');
            app.RedButton.Text = 'Red';
            app.SelectInkCartridgetoChangeLabel = uilabel(app.UIFigure);
            app.SelectInkCartridgetoChangeLabel.Text = {'Select Ink Cartridge'; 'to Change'};
            app.RedInventoryGaugeLabel = uilabel(app.UIFigure);
            app.RedInventoryGaugeLabel.Text = 'Red Inventory';
            app.RedInventoryGauge = uigauge(app.UIFigure, 'linear');
            app.RedInventoryGauge.Limits = [0 10];
            app.RedInventoryGauge.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.GreenInventoryGaugeLabel = uilabel(app.UIFigure);
            app.GreenInventoryGaugeLabel.Text = 'Green Inventory';
            app.GreenInventoryGauge = uigauge(app.UIFigure, 'linear');
            app.GreenInventoryGauge.Limits = [0 10];
            app.GreenInventoryGauge.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.GreenButton = uibutton(app.UIFigure, 'push');
            app.GreenButton.Text = 'Green';
            app.BlueInventoryGaugeLabel = uilabel(app.UIFigure);
            app.BlueInventoryGaugeLabel.Text = 'Blue Inventory';
            app.BlueInventoryGauge = uigauge(app.UIFigure, 'linear');
            app.BlueInventoryGauge.Limits = [0 10];
            app.BlueInventoryGauge.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.BlueButton = uibutton(app.UIFigure, 'push');
            app.BlueButton.Text = 'Blue';
            app.RedCapacityGaugeLabel = uilabel(app.UIFigure);
            app.RedCapacityGaugeLabel.Text = 'Red Capacity';
            app.RedCapacityGauge = uigauge(app.UIFigure, 'linear');
            app.GreenCapacityGaugeLabel = uilabel(app.UIFigure);
            app.GreenCapacityGaugeLabel.Text = 'Green Capacity';
            app.GreenCapacityGauge = uigauge(app.UIFigure, 'linear');
            app.BlueCapacityGaugeLabel = uilabel(app.UIFigure);
            app.BlueCapacityGaugeLabel.Text = 'Blue Capacity';
            app.BlueCapacityGauge = uigauge(app.UIFigure, 'linear');
            app.EmergencyStopButton = uibutton(app.UIFigure, 'state');
            app.EmergencyStopButton.Text = 'Emergency Stop';
            app.PowerButton = uibutton(app.UIFigure, 'state');
            app.PowerButton.ValueChangedFcn = createCallbackFcn(app, @powerOn, true);
            app.PowerButton.Text = 'Power';
            app.ARMSwitchLabel = uilabel(app.UIFigure);
            app.ARMSwitchLabel.Text = 'ARM';
            app.ARMSwitch = uiswitch(app.UIFigure, 'toggle');
            app.ARMSwitch.ValueChangedFcn = createCallbackFcn(app, @powerOn, true);
            app.Lamp_redCap = uilamp(app.UIFigure);
            app.Lamp_greenCap = uilamp(app.UIFigure);
            app.Lamp_blueCap = uilamp(app.UIFigure);
            app.Lamp_blueInv = uilamp(app.UIFigure);
            app.Lamp_greenInv = uilamp(app.UIFigure);
            app.Lamp_redInv = uilamp(app.UIFigure);
            app.StatusLampLabel = uilabel(app.UIFigure);
            app.StatusLampLabel.Text = 'Status:';
            app.Lamp_power = uilamp(app.UIFigure);
            app.Lamp_ARM = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end